classdef GUIv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        EngineParametersTab            matlab.ui.container.Tab
        DesignPointInputsLabel         matlab.ui.control.Label
        OffDesignSetupLabel            matlab.ui.control.Label
        RUNANALYSISButton              matlab.ui.control.Button
        YearDesignedEditFieldLabel     matlab.ui.control.Label
        YearDesignedEditField          matlab.ui.control.NumericEditField
        BypassRatioEditFieldLabel      matlab.ui.control.Label
        BypassRatioEditField           matlab.ui.control.NumericEditField
        BleedRatioEditFieldLabel       matlab.ui.control.Label
        BleedRatioEditField            matlab.ui.control.NumericEditField
        PTOHighWEditFieldLabel         matlab.ui.control.Label
        PTOHighWEditField              matlab.ui.control.NumericEditField
        PTOLowWEditFieldLabel          matlab.ui.control.Label
        PTOLowWEditField               matlab.ui.control.NumericEditField
        InletDiametermEditFieldLabel   matlab.ui.control.Label
        InletDiametermEditField        matlab.ui.control.NumericEditField
        pi_cLSliderLabel               matlab.ui.control.Label
        pi_cLSlider                    matlab.ui.control.Slider
        pi_cHSliderLabel               matlab.ui.control.Label
        pi_cHSlider                    matlab.ui.control.Slider
        AltitudeEditFieldLabel         matlab.ui.control.Label
        AltitudeEditField              matlab.ui.control.NumericEditField
        MachNumberEditFieldLabel       matlab.ui.control.Label
        MachNumberEditField            matlab.ui.control.NumericEditField
        MinimumAltitudeEditFieldLabel  matlab.ui.control.Label
        MinimumAltitudeEditField       matlab.ui.control.NumericEditField
        MaximumAltitudeEditFieldLabel  matlab.ui.control.Label
        MaximumAltitudeEditField       matlab.ui.control.NumericEditField
        MaximumMachNumberEditFieldLabel  matlab.ui.control.Label
        MaximumMachNumberEditField     matlab.ui.control.NumericEditField
        NumberofCurvesDesiredSpinnerLabel  matlab.ui.control.Label
        NumberofCurvesDesiredSpinner   matlab.ui.control.Spinner
        MinimumMachNumberEditField_2Label  matlab.ui.control.Label
        MinimumMachNumberEditField     matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        ChartTab                       matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function myprogress1
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
                'Message','Opening the application');
            pause(.5)
            
            % Perform calculations
            % ...
            d.Value = .33;
            d.Message = 'Loading your data';
            pause(1)
            
            % Perform calculations
            % ...
            d.Value = .67;
            d.Message = 'Processing the data';
            pause(1)
            
            % Finish calculations
            % ...
            d.Value = 1;
            d.Message = 'Finishing';
            pause(1)
            
            % Close dialog box
            close(d);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNANALYSISButton
        function RUNANALYSISButtonPushed(app, event)
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
                'Message','Running Engine Performance Analysis');
            pause(.2)
            
            altft = app.AltitudeEditField.Value;
            alt = altft/3.281;
            M0 = app.MachNumberEditField.Value;
            
            pi_cL = app.pi_cLSlider.Value;
            pi_cH = app.pi_cHSlider.Value;
            
            alpha = app.BypassRatioEditField.Value;
            [pi_f] = optimalfan(alpha);
            beta = app.BleedRatioEditField.Value;
            PtoH = app.PTOHighWEditField.Value;
            PtoL = app.PTOLowWEditField.Value;
            D0 = app.InletDiametermEditField.Value;
            A0 = pi*(D0/2)^2;
            year = app.YearDesignedEditField.Value;
            [state,component,design,inputs,performance] = on_design(alt,M0,pi_f,pi_cL,pi_cH,alpha,beta,PtoH,PtoL,A0,year);


            M1 = app.MinimumMachNumberEditField.Value;
            Mn = app.MaximumMachNumberEditField.Value;
            A1 = app.MinimumAltitudeEditField.Value;
            An = app.MaximumAltitudeEditField.Value;
            n = app.NumberofCurvesDesiredSpinner.Value;
            
            alt = linspace(A1,An,n);
            alt = alt./3.281;
            M0 = linspace(M1,Mn,15); %Operational envelope
            
            S = zeros(size(alt));
            F = zeros(size(alt));
            
            for i = 1:length(alt)
                for j = 1:length(M0)
                  
                    [~,~,~,~,performance_i] = off_design2(state,component,design,inputs,M0(j),alt(i),A0);
                    
                    S(i,j) = performance_i{2,2} / ((.453592/3600)/4.44822);
                    F(i,j) = performance_i{2,1} * 0.224809;
                    d.Value = i/length(alt);
                    d.Message = 'Running Engine Performance Analysis';
                    
                end
            end
            
           
            for i = 1:length(alt)
                plot(app.UIAxes,M0,F(i,:),'linewidth',1.5)
                hold on
               
            end
            
            
            close(f);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EngineParametersTab = uitab(app.TabGroup);
            app.EngineParametersTab.Title = 'Engine Parameters';
            app.DesignPointInputsLabel = uilabel(app.EngineParametersTab);
            app.DesignPointInputsLabel.Text = 'Design Point Inputs';
            app.OffDesignSetupLabel = uilabel(app.EngineParametersTab);
            app.OffDesignSetupLabel.Text = 'Off-Design Setup';
            app.RUNANALYSISButton = uibutton(app.EngineParametersTab, 'push');
            app.RUNANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @RUNANALYSISButtonPushed, true);
            app.RUNANALYSISButton.Text = 'RUN ANALYSIS';
            app.YearDesignedEditFieldLabel = uilabel(app.EngineParametersTab);
            app.YearDesignedEditFieldLabel.Text = 'Year Designed';
            app.YearDesignedEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.YearDesignedEditField.Value = 2011;
            app.BypassRatioEditFieldLabel = uilabel(app.EngineParametersTab);
            app.BypassRatioEditFieldLabel.Text = 'Bypass Ratio';
            app.BypassRatioEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.BypassRatioEditField.Value = 6.9;
            app.BleedRatioEditFieldLabel = uilabel(app.EngineParametersTab);
            app.BleedRatioEditFieldLabel.Text = 'Bleed Ratio';
            app.BleedRatioEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.PTOHighWEditFieldLabel = uilabel(app.EngineParametersTab);
            app.PTOHighWEditFieldLabel.Text = 'PTO High (W)';
            app.PTOHighWEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.PTOLowWEditFieldLabel = uilabel(app.EngineParametersTab);
            app.PTOLowWEditFieldLabel.Text = 'PTO Low (W)';
            app.PTOLowWEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.InletDiametermEditFieldLabel = uilabel(app.EngineParametersTab);
            app.InletDiametermEditFieldLabel.Text = 'Inlet Diameter (m)';
            app.InletDiametermEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.InletDiametermEditField.Value = 0.469;
            app.pi_cLSliderLabel = uilabel(app.EngineParametersTab);
            app.pi_cLSliderLabel.Text = 'pi_cL';
            app.pi_cLSlider = uislider(app.EngineParametersTab);
            app.pi_cLSlider.Limits = [1 10];
            app.pi_cLSlider.Value = 3;
            app.pi_cHSliderLabel = uilabel(app.EngineParametersTab);
            app.pi_cHSliderLabel.Text = 'pi_cH';
            app.pi_cHSlider = uislider(app.EngineParametersTab);
            app.pi_cHSlider.Limits = [1 10];
            app.pi_cHSlider.Value = 9;
            app.AltitudeEditFieldLabel = uilabel(app.EngineParametersTab);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.AltitudeEditField.ValueDisplayFormat = '%.0f';
            app.MachNumberEditFieldLabel = uilabel(app.EngineParametersTab);
            app.MachNumberEditFieldLabel.Text = 'Mach Number';
            app.MachNumberEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.MachNumberEditField.ValueDisplayFormat = '%.3f';
            app.MachNumberEditField.Value = 0.1;
            app.MinimumAltitudeEditFieldLabel = uilabel(app.EngineParametersTab);
            app.MinimumAltitudeEditFieldLabel.Text = 'Minimum Altitude';
            app.MinimumAltitudeEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.MinimumAltitudeEditField.ValueDisplayFormat = '%.0f';
            app.MaximumAltitudeEditFieldLabel = uilabel(app.EngineParametersTab);
            app.MaximumAltitudeEditFieldLabel.Text = 'Maximum Altitude';
            app.MaximumAltitudeEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.MaximumAltitudeEditField.ValueDisplayFormat = '%.0f';
            app.MaximumAltitudeEditField.Value = 25000;
            app.MaximumMachNumberEditFieldLabel = uilabel(app.EngineParametersTab);
            app.MaximumMachNumberEditFieldLabel.Text = 'Maximum Mach Number';
            app.MaximumMachNumberEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.MaximumMachNumberEditField.ValueDisplayFormat = '%.3f';
            app.MaximumMachNumberEditField.Value = 0.45;
            app.NumberofCurvesDesiredSpinnerLabel = uilabel(app.EngineParametersTab);
            app.NumberofCurvesDesiredSpinnerLabel.Text = 'Number of Curves Desired';
            app.NumberofCurvesDesiredSpinner = uispinner(app.EngineParametersTab);
            app.NumberofCurvesDesiredSpinner.RoundFractionalValues = 'on';
            app.NumberofCurvesDesiredSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofCurvesDesiredSpinner.Value = 3;
            app.MinimumMachNumberEditField_2Label = uilabel(app.EngineParametersTab);
            app.MinimumMachNumberEditField_2Label.Text = 'Minimum Mach Number';
            app.MinimumMachNumberEditField = uieditfield(app.EngineParametersTab, 'numeric');
            app.MinimumMachNumberEditField.ValueDisplayFormat = '%.3f';
            app.MinimumMachNumberEditField.Value = 0.1;
            app.ChartTab = uitab(app.TabGroup);
            app.ChartTab.Title = 'Chart';
            app.UIAxes = uiaxes(app.ChartTab);
            title(app.UIAxes, 'Thrust vs. Mach Number')
            xlabel(app.UIAxes, 'Mach Number')
            ylabel(app.UIAxes, 'Thrust')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end